classdef enhance < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EnhanceUIFigure         matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        OpenImageMenu           matlab.ui.container.Menu
        TabGroup                matlab.ui.container.TabGroup
        OriginaltoGrayscaleTab  matlab.ui.container.Tab
        UIAxes_2                matlab.ui.control.UIAxes
        GrayscaleButton         matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        GrayscaletoBinaryTab    matlab.ui.container.Tab
        UIAxes_3                matlab.ui.control.UIAxes
        UIAxes_4                matlab.ui.control.UIAxes
        BinaryButton            matlab.ui.control.Button
        Tab3                    matlab.ui.container.Tab
    end


    methods (Access = private)
    
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EnhanceUIFigure.Name = 'Images';
            app.EnhanceUIFigure.WindowStyle ='kkk';
        end

        % Menu selected function: OpenImageMenu
        function OpenImageMenuSelected(app, event)
            global img;
            [nama_file, nama_path] = uigetfile('*.png;*.jpg;*.bmp;*.gif','Select Image');
            if ~isequal (nama_file,0)
                img = imread(fullfile(nama_path,nama_file));
                imshow(img,'Parent',app.UIAxes); 
            else
                return;
            end
        end

        % Button pushed function: GrayscaleButton
        function GrayscaleButtonPushed(app, event)
            global img;
            global gray;
            gray = rgb2gray(img);
            imshow(gray,'Parent',app.UIAxes_2); 
            imshow(gray,'Parent',app.UIAxes_3); 
        end

        % Button pushed function: BinaryButton
        function BinaryButtonPushed(app, event)
            global gray;
            bin = imbinarize(gray);
            imshow(bin,'Parent',app.UIAxes_4); 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EnhanceUIFigure = uifigure;
            app.EnhanceUIFigure.Name = 'Enhance';
            app.FileMenu = uimenu(app.EnhanceUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenImageMenu = uimenu(app.FileMenu);
            app.OpenImageMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenImageMenuSelected, true);
            app.OpenImageMenu.Accelerator = 'O';
            app.OpenImageMenu.Text = 'Open Image';
            app.TabGroup = uitabgroup(app.EnhanceUIFigure);
            app.OriginaltoGrayscaleTab = uitab(app.TabGroup);
            app.OriginaltoGrayscaleTab.Title = 'Original to Grayscale';
            app.UIAxes_2 = uiaxes(app.OriginaltoGrayscaleTab);
            title(app.UIAxes_2, 'Grayscale Image')
            app.GrayscaleButton = uibutton(app.OriginaltoGrayscaleTab, 'push');
            app.GrayscaleButton.ButtonPushedFcn = createCallbackFcn(app, @GrayscaleButtonPushed, true);
            app.GrayscaleButton.Text = 'Grayscale';
            app.UIAxes = uiaxes(app.OriginaltoGrayscaleTab);
            title(app.UIAxes, 'Orginal Image')
            app.GrayscaletoBinaryTab = uitab(app.TabGroup);
            app.GrayscaletoBinaryTab.Title = 'Grayscale to Binary';
            app.UIAxes_3 = uiaxes(app.GrayscaletoBinaryTab);
            title(app.UIAxes_3, 'Grayscale Image')
            app.UIAxes_4 = uiaxes(app.GrayscaletoBinaryTab);
            title(app.UIAxes_4, 'Binary Image')
            app.BinaryButton = uibutton(app.GrayscaletoBinaryTab, 'push');
            app.BinaryButton.ButtonPushedFcn = createCallbackFcn(app, @BinaryButtonPushed, true);
            app.BinaryButton.Text = 'Binary';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
        end
    end

    methods (Access = public)

        % Construct app
        function app = enhance

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EnhanceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EnhanceUIFigure)
        end
    end
end